classdef PathOptimizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PathOptimizationAppUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ModelSetupTab                   matlab.ui.container.Tab
        VehicleDropDownLabel            matlab.ui.control.Label
        VehicleDropDown                 matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        PlotVehicleDataButtonGroup      matlab.ui.container.ButtonGroup
        MaximumAccelerationButton       matlab.ui.control.RadioButton
        MaximumDecelerationButton       matlab.ui.control.RadioButton
        MaximumCorneringVelocityButton  matlab.ui.control.RadioButton
        OptimizationTab                 matlab.ui.container.Tab
        ObjectiveDropDownLabel          matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        TrackDropDownLabel              matlab.ui.control.Label
        TrackDropDown                   matlab.ui.control.DropDown
        UIAxes2                         matlab.ui.control.UIAxes
        ConfigureOptimizerButton        matlab.ui.control.Button
        SectionwiseopimizationCheckBox  matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        ConfigureEvaluatorButton        matlab.ui.control.Button
        ParameterMultiplierLabel        matlab.ui.control.Label
        ParameterMultiplierSlider       matlab.ui.control.Slider
        NumberofParametersLabel         matlab.ui.control.Label
        ParameterCountLabel             matlab.ui.control.Label
        ConfigureLocalEvaluatorButton   matlab.ui.control.Button
        SectionRefinementSliderLabel    matlab.ui.control.Label
        SectionRefinementSlider         matlab.ui.control.Slider
        sectionRefinementLabel          matlab.ui.control.Label
        OptimizationResultsTab          matlab.ui.container.Tab
        VehicleParametersTab            matlab.ui.container.Tab
        DoyouwanttochoosevehiclecontrollerparametersButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoUsethedefaultparametersButton  matlab.ui.control.RadioButton
        EntertheParametersLabel         matlab.ui.control.Label
        EntertheParametersLabel_2       matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        VehicleMassEditFieldLabel       matlab.ui.control.Label
        VehicleMassEditField            matlab.ui.control.NumericEditField
        VehicleWheelbaseEditFieldLabel  matlab.ui.control.Label
        VehicleWheelbaseEditField       matlab.ui.control.NumericEditField
        AxleHeightEditFieldLabel        matlab.ui.control.Label
        AxleHeightEditField             matlab.ui.control.NumericEditField
        CenterofGravitylocationfromthefrontaxleEditFieldLabel  matlab.ui.control.Label
        CenterofGravitylocationfromthefrontaxleEditField  matlab.ui.control.NumericEditField
        FrontTireCornerStifnessEditFieldLabel  matlab.ui.control.Label
        FrontTireCornerStifnessEditField  matlab.ui.control.NumericEditField
        RearTireCornerStifnessEditFieldLabel  matlab.ui.control.Label
        RearTireCornerStifnessEditField  matlab.ui.control.NumericEditField
        CoefficientofFrictionEditFieldLabel  matlab.ui.control.Label
        CoefficientofFrictionEditField  matlab.ui.control.NumericEditField
        SteeringAgressivenessEditFieldLabel  matlab.ui.control.Label
        SteeringAgressivenessEditField  matlab.ui.control.NumericEditField
        MaxsteeringangleradEditFieldLabel  matlab.ui.control.Label
        MaxsteeringangleradEditField    matlab.ui.control.NumericEditField
        MinsteeringangleradEditFieldLabel  matlab.ui.control.Label
        MinsteeringangleradEditField    matlab.ui.control.NumericEditField
        PredictionHorizonstepsEditFieldLabel  matlab.ui.control.Label
        PredictionHorizonstepsEditField  matlab.ui.control.NumericEditField
        SimulationTab                   matlab.ui.container.Tab
        PleasechoosehowdoyouwanttosimulatetheresultsLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        SimulationOptionsDropDownLabel  matlab.ui.control.Label
        SimulationOptionsDropDown       matlab.ui.control.DropDown
        ParametersEstimationTab         matlab.ui.container.Tab
        WhichParametersdoyouwanttooptimizeLabel  matlab.ui.control.Label
        VehicleMassCheckBox             matlab.ui.control.CheckBox
        VehicleWheelbaseCheckBox        matlab.ui.control.CheckBox
        LocationofcenterofgravityCheckBox  matlab.ui.control.CheckBox
        AxleHeightCheckBox              matlab.ui.control.CheckBox
        StartButton_2                   matlab.ui.control.Button
        MassRangeEditField_max          matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        WheelbaseRangeEditField_max     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        COGRangeEditField_max           matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        AxleHeightRangeEditField_max    matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        MassRangeEditField              matlab.ui.control.NumericEditField
        WheelbaseRangeEditField         matlab.ui.control.NumericEditField
        COGRangeEditField               matlab.ui.control.NumericEditField
        AxleHeightRangeEditField        matlab.ui.control.NumericEditField
        ParametersLabel                 matlab.ui.control.Label
        FrontTireCornerStiffnessCheckBox  matlab.ui.control.CheckBox
        RearTireCornerStiffnessCheckBox  matlab.ui.control.CheckBox
        CoefficientofFrictionCheckBox   matlab.ui.control.CheckBox
        SteeringAgressivenessCheckBox   matlab.ui.control.CheckBox
        FrontTireCornerStiffness_max    matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        RearTireCornerStiffness_max     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        CoefficientofFriction_max       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        SteeringAgressiveness_max       matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        FrontTireCornerStiffness_min    matlab.ui.control.NumericEditField
        RearTireCornerStiffness_min     matlab.ui.control.NumericEditField
        CoefficientofFriction_min       matlab.ui.control.NumericEditField
        SteeringAgressiveness_min       matlab.ui.control.NumericEditField
        RangeLabel                      matlab.ui.control.Label
        MaxsteeringangleradCheckBox     matlab.ui.control.CheckBox
        MinsteeringangleradCheckBox     matlab.ui.control.CheckBox
        PredictionHorizonstepsCheckBox  matlab.ui.control.CheckBox
        Max_steering_max                matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        Min_steering_max                matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        Prediction_Horizon_max          matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Max_steering_min                matlab.ui.control.NumericEditField
        Min_steering_min                matlab.ui.control.NumericEditField
        Prediction_Horizon_min          matlab.ui.control.NumericEditField
        MassEditField                   matlab.ui.control.NumericEditField
        WheelbaseEditField              matlab.ui.control.NumericEditField
        COGEditField                    matlab.ui.control.NumericEditField
        AxleHeightEditField_2           matlab.ui.control.NumericEditField
        FrontTireCornerStiffness_result  matlab.ui.control.NumericEditField
        RearTireCornerStiffness_result  matlab.ui.control.NumericEditField
        CoefficientofFriction_result    matlab.ui.control.NumericEditField
        SteeringAgressiveness_result    matlab.ui.control.NumericEditField
        Max_steering_result             matlab.ui.control.NumericEditField
        Min_steering_result             matlab.ui.control.NumericEditField
        Prediction_Horizon_result       matlab.ui.control.NumericEditField
        ResultsLabel                    matlab.ui.control.Label
    end

    
    properties (Access = public)
        vehicleModel                    % Structure with 3 lookups:
                                        % - maxAcceleration
                                        % - maxDeceleration
                                        % - maxCorneringVelocity
        objectiveFunction               % Objective function used in the optimization
        track                           % Track to generate the path on
        evaluator                       % Evaluator instance used in the optimization 
                                        %   (see the documentation)
        evaluatorSettings               % Store user settable evaluator parameters
        terminateOptimization           % The optimizer checks this variable after each iteration
                                        % and stops if it's true
        localEvaluator                  % Evaluator for sectionwise optimization
        localEvaluatorSettings          % Store user settable evaluator parameters for sectionwise optimization
        sectionRefinement               % Parameter multiplier for sectionwise optimization
        params                          % Optimization target parameters
    end
    
    properties (Access = private)
        activeAxes                      % Handle of the currently displayed axes
        plotBlock                       % Local plot blocking flag
        optimizationOptions             % Subset of optimoptions('fmincon') that can be 
                                        % modified by the user
    end
    
    methods (Access = private)
        
        % Load vehicle data from inc/dynamics/vehicles/vehicleName
        function loadVehicleData(app, vehicleName)
            app.vehicleModel.maxAcceleration =                  ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/accelerationTable.csv'],      ...
                                'linearSearch');
            app.vehicleModel.maxDeceleration =                  ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/decelerationTable.csv'],      ...
                                'linearSearch');
            app.vehicleModel.maxCorneringVelocity =             ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/corneringVelocityTable.csv'], ...
                                'linearSearch');
        end
        
        % Plot arguments in the active axes
        function plot(app, x, y, varargin)
            if ~app.plotBlock
                cla(app.activeAxes);
                plot( app.activeAxes, x, y, varargin{:} );
                app.formatAxes();
            end
        end
        
        % Call plotter function in the active axes
        function plotFunction(app,func,varargin)
            if ~app.plotBlock
                cla(app.activeAxes);
                func(app.activeAxes);
                app.formatAxes(varargin{:});
            end
        end
        
        function formatAxes(app,varargin)
            if ~app.plotBlock
                % Set axis tick precision
                xtickformat(app.activeAxes,'%3d')
                ytickformat(app.activeAxes,'%3d')
                % Get axes ranges
                rng = [Inf,-Inf,Inf,-Inf];
                for k=1:length(app.activeAxes.Children)
                    rng = [ min( rng(1), min( app.activeAxes.Children(k).XData ) ), ...
                            max( rng(2), max( app.activeAxes.Children(k).XData ) ), ...
                            min( rng(3), min( app.activeAxes.Children(k).YData ) ), ...
                            max( rng(4), max( app.activeAxes.Children(k).YData ) ) ];
                end
                % Apply axes ranges
                axis(app.activeAxes, rng);
                % Set axes equal if requested
                if any( strcmp(varargin, 'axis equal') )
                    axis(app.activeAxes,'equal');
                end
                % Set aspect ratio
                pbaspect(app.activeAxes, [1.0,1.0,1.0]);
            end
        end
        
        
        function toggleSectionwiseSettings(app)
            if app.SectionwiseopimizationCheckBox.Value
                app.ConfigureLocalEvaluatorButton.Visible   = true;
                app.SectionRefinementSlider.Visible         = true;
                app.SectionRefinementSliderLabel.Visible    = true;
                app.sectionRefinementLabel.Visible          = true;
            else
                app.ConfigureLocalEvaluatorButton.Visible   = false;
                app.SectionRefinementSlider.Visible         = false;
                app.SectionRefinementSliderLabel.Visible    = false;
                app.sectionRefinementLabel.Visible          = false;
            end
        end
        
        
        function setCommonEvaluatorProperties(app,varargin)
            for k=1:2:length(varargin)
                app.evaluator.(varargin{k})         = varargin{k+1};
                app.localEvaluator.(varargin{k})    = varargin{k+1};
            end
        end


        function runPostProcessor(app)
            % Select the evaluator
            evaluator = app.evaluator;
            if (app.SectionwiseopimizationCheckBox.Value)
                evaluator = app.localEvaluator;
            end
            % Configure evaluator
            evaluator.writeDetails  = true;
            evaluator.optimRange    = 0;
            % Run evaluation one last time to get the data
            evaluatePath(app.track,app.params,evaluator);
            % Run postprocessing
            if ~isempty(evaluator.velocities)
                app.plotFunction( @(ax) plotSolutionOnAxes(ax, app.track, app.params, evaluator) );
                plotEvaluatorOnAxes( app.OptimizationResultsTab, evaluator );
            end
            % Save results
            alpha   = app.params;
            track   = app.track;
            if ~isempty(evaluator.time)
                tLap = evaluator.time(end);
            else
                tLap = 0;
            end
            save('doc/resultOutput/AppOutput.mat','alpha','evaluator','tLap','track');
        end
        
        
         function [mass_check,wheelbase_check,cog_check,h_check,f_tire_check,r_tire_check,mu_check,agr_check,min_check,max_check,pred_check] = parameters(app)
            % function to check which parameter(s) is/are selected for
            % optimization
            if app.VehicleMassCheckBox.Value
                mass_check='mass';
            else
                mass_check='';
            end
            if app.VehicleWheelbaseCheckBox.Value
                wheelbase_check='wheelbase';
            else
                wheelbase_check='';
            end
            if app.LocationofcenterofgravityCheckBox.Value
                cog_check='dfront';
            else
                cog_check='';
            end
            if app.AxleHeightCheckBox.Value
                h_check='h';
            else
                h_check='';
            end   
            if app.FrontTireCornerStiffnessCheckBox.Value
                f_tire_check='f_tire_stiff';
            else
                f_tire_check='';
            end
            if app.RearTireCornerStiffnessCheckBox.Value
                r_tire_check='r_tire_stiff';
            else
                r_tire_check='';
            end   
            if app.CoefficientofFrictionCheckBox.Value
                mu_check='mu';
            else
                mu_check='';
            end      
            if app.SteeringAgressivenessCheckBox.Value
                agr_check='aggressive';
            else
                agr_check='';
            end            
            if app.MinsteeringangleradCheckBox.Value
                min_check='min_steer';
            else
                min_check='';
            end  
            if app.MaxsteeringangleradCheckBox.Value
                max_check='max_steer';
            else
                max_check='';
            end              
            if app.PredictionHorizonstepsCheckBox.Value
                pred_check='pred_Horizon';
            else
                pred_check='';   
            end
        end
        
        function on_off(app)
            % Function to switch the Edit Fields on and off depending on
            % whether the user wants to select the parameters or not
            if app.YesButton.Value
                app.VehicleMassEditField.Visible=true;
                app.AxleHeightEditField.Visible=true;
                app.CenterofGravitylocationfromthefrontaxleEditField.Visible=true;
                app.VehicleWheelbaseEditField.Visible=true;
                app.EntertheParametersLabel.Visible=true;
                app.EntertheParametersLabel_2.Visible=true;
                app.VehicleMassEditFieldLabel.Visible=true;
                app.AxleHeightEditFieldLabel.Visible=true;
                app.VehicleWheelbaseEditFieldLabel.Visible=true;
                app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Visible=true;
                app.MassRangeEditField.Visible=true;
                app.MassRangeEditField_max.Visible=true;
                app.Label.Visible=true;
                app.WheelbaseRangeEditField_max.Visible=true;
                app.Label_2.Visible=true;
                app.WheelbaseRangeEditField.Visible=true;
                app.COGRangeEditField_max.Visible=true;
                app.Label_3.Visible=true;
                app.COGRangeEditField.Visible=true;
                app.AxleHeightRangeEditField.Visible=true;                
                app.AxleHeightRangeEditField_max.Visible=true;
                app.Label_4.Visible=true;
                app.FrontTireCornerStifnessEditField.Visible=true;
                app.RearTireCornerStifnessEditField.Visible=true;
                app.CoefficientofFrictionEditField.Visible=true;
                app.SteeringAgressivenessEditField.Visible= true;
                app.MaxsteeringangleradEditField.Visible=true;
                app.MinsteeringangleradEditField.Visible=true;
                app.PredictionHorizonstepsEditField.Visible=true;
                app.FrontTireCornerStifnessEditFieldLabel.Visible=true;
                app.RearTireCornerStifnessEditFieldLabel.Visible=true;
                app.CoefficientofFrictionEditFieldLabel.Visible=true;
                app.SteeringAgressivenessEditFieldLabel.Visible= true;
                app.MaxsteeringangleradEditFieldLabel.Visible=true;
                app.MinsteeringangleradEditFieldLabel.Visible=true;
                app.PredictionHorizonstepsEditFieldLabel.Visible=true;
                app.FrontTireCornerStiffness_max.Visible=true;
                app.FrontTireCornerStiffness_min.Visible=true;
                app.RearTireCornerStiffness_max.Visible=true;
                app.RearTireCornerStiffness_min.Visible=true;
                app.Label_5.Visible=true;
                app.CoefficientofFriction_max.Visible=true;
                app.CoefficientofFriction_min.Visible=true;
                app.Label_6.Visible=true;
                app.SteeringAgressiveness_max.Visible=true;
                app.SteeringAgressiveness_min.Visible=true;
                app.Label_7.Visible=true;
                app.Min_steering_max.Visible=true;
                app.Min_steering_min.Visible=true;
                app.Label_8.Visible=true;                
                app.Max_steering_max.Visible=true;
                app.Max_steering_min.Visible=true;
                app.Label_9.Visible=true;
                app.Prediction_Horizon_max.Visible=true;
                app.Prediction_Horizon_min.Visible=true;
                app.Label_10.Visible=true;
                app.Label_11.Visible=true;
                app.RangeLabel.Visible=true;
               
            else
                app.AxleHeightRangeEditField.Visible=false;            
                app.AxleHeightRangeEditField_max.Visible=false;
                app.VehicleMassEditField.Visible=false;
                app.AxleHeightEditField.Visible=false;
                app.CenterofGravitylocationfromthefrontaxleEditField.Visible=false;
                app.VehicleWheelbaseEditField.Visible=false;
                app.EntertheParametersLabel.Visible=false;
                app.EntertheParametersLabel_2.Visible=false;   
                app.VehicleMassEditFieldLabel.Visible=false;
                app.AxleHeightEditFieldLabel.Visible=false;
                app.VehicleWheelbaseEditFieldLabel.Visible=false;
                app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Visible=false;    
                app.MassRangeEditField.Visible=false;
                app.MassRangeEditField_max.Visible=false;
                app.Label.Visible=false;
                app.WheelbaseRangeEditField_max.Visible=false;
                app.Label_2.Visible=false;
                app.WheelbaseRangeEditField.Visible=false;
                app.COGRangeEditField_max.Visible=false;
                app.Label_3.Visible=false;
                app.COGRangeEditField.Visible=false;
                app.Label_4.Visible=false;
                app.FrontTireCornerStifnessEditField.Visible=false;
                app.RearTireCornerStifnessEditField.Visible=false;
                app.CoefficientofFrictionEditField.Visible=false;
                app.SteeringAgressivenessEditField.Visible= false;
                app.MaxsteeringangleradEditField.Visible=false;
                app.MinsteeringangleradEditField.Visible=false;
                app.PredictionHorizonstepsEditField.Visible=false;
                app.FrontTireCornerStifnessEditFieldLabel.Visible=false;
                app.RearTireCornerStifnessEditFieldLabel.Visible=false;
                app.CoefficientofFrictionEditFieldLabel.Visible=false;
                app.SteeringAgressivenessEditFieldLabel.Visible= false;
                app.MaxsteeringangleradEditFieldLabel.Visible=false;
                app.MinsteeringangleradEditFieldLabel.Visible=false;
                app.PredictionHorizonstepsEditFieldLabel.Visible=false;   
                app.FrontTireCornerStiffness_max.Visible=false;
                app.FrontTireCornerStiffness_min.Visible=false;
                app.RearTireCornerStiffness_max.Visible=false;
                app.RearTireCornerStiffness_min.Visible=false;
                app.Label_5.Visible=false;
                app.CoefficientofFriction_max.Visible=false;
                app.CoefficientofFriction_min.Visible=false;
                app.Label_6.Visible=false;
                app.SteeringAgressiveness_max.Visible=false;
                app.SteeringAgressiveness_min.Visible=false;
                app.Label_7.Visible=false;
                app.Min_steering_max.Visible=false;
                app.Min_steering_min.Visible=false;
                app.Label_8.Visible=false;         
                app.Max_steering_max.Visible=false;
                app.Max_steering_min.Visible=false;
                app.Label_9.Visible=false;
                app.Prediction_Horizon_max.Visible=false;
                app.Prediction_Horizon_min.Visible=false;
                app.Label_10.Visible=false;      
                app.Label_10.Visible=false;      
                app.Label_11.Visible=false;      
                app.RangeLabel.Visible=false;
                
            end            
        end
        
        function set_parm(app)
            % Funtion to set the parameters/limits to the default values
            if ~app.YesButton.Value
                app.VehicleMassEditField.Value=2500;
                app.VehicleWheelbaseEditField.Value=2.8;
                app.CenterofGravitylocationfromthefrontaxleEditField.Value=1.2;
                app.AxleHeightEditField.Value=0.35;    
                app.MassRangeEditField.Value=700;
                app.MassRangeEditField_max.Value=2500;
                app.WheelbaseRangeEditField.Value=2.5;
                app.WheelbaseRangeEditField_max.Value=6;
                app.COGRangeEditField.Value=1;
                app.COGRangeEditField_max.Value=1.5;
                app.AxleHeightRangeEditField.Value=0.135;
                app.AxleHeightRangeEditField_max.Value=0.36;   
                app.FrontTireCornerStifnessEditField.Value=40000;
                app.RearTireCornerStifnessEditField.Value=40000;
                app.CoefficientofFrictionEditField.Value=1;
                app.SteeringAgressivenessEditField.Value= 0.2;
                app.MaxsteeringangleradEditField.Value= 0.19;
                app.MinsteeringangleradEditField.Value=-0.19;
                app.PredictionHorizonstepsEditField.Value=10;   
                app.FrontTireCornerStiffness_max.Value=40000;
                app.FrontTireCornerStiffness_min.Value=10000;
                app.RearTireCornerStiffness_max.Value=40000;
                app.RearTireCornerStiffness_min.Value=10000;
                app.CoefficientofFriction_max.Value=1.5;
                app.CoefficientofFriction_min.Value=0.7;
                app.SteeringAgressiveness_max.Value=1;
                app.SteeringAgressiveness_min.Value=0;
                app.Min_steering_max.Value=0;
                app.Min_steering_min.Value=-1;
                app.Max_steering_max.Value=1;
                app.Max_steering_min.Value=0;
                app.Prediction_Horizon_max.Value=50;
                app.Prediction_Horizon_min.Value=10;                
            end            
        end
        % ------------------------------------------------------------------


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set MATLAB path
            init();
            % Set plot block
            app.plotBlock   = false;
            
            % Initialize global variables
            app.terminateOptimization   = false;
            
            % Prevent figure resizing (make subplot work in the app)
            app.OptimizationResultsTab.AutoResizeChildren = 'off';
            
            %% INITIALIZE MODEL SETUP TAB -----------------------------------------------------
            % Set active axes
            app.activeAxes  = app.UIAxes;
            % Initialize evaluator
            app.evaluator       = Evaluator();
            app.localEvaluator  = Evaluator();
            % Discover available vehicle models and set default
            app.VehicleDropDown.Items       = listItemsInFolder('inc/dynamics/vehicles');
            app.VehicleDropDown.set('Value',    app.VehicleDropDown.Items{1});
            app.VehicleDropDownValueChanged('NoneEvent');
            
            %% INITIALIZE OPTIMIZATION TAB ----------------------------------------------------
            % Set active axes
            app.activeAxes  = app.UIAxes2;
            % Discover available objective functions and set default
            objectives                      = listItemsInFolder('inc/pathOptimization', 'objective_');
            app.ObjectiveDropDown.Items     = stripExtensions(objectives,'.m');
            app.ObjectiveDropDown.set('Value',  app.ObjectiveDropDown.Items{1});
            app.ObjectiveDropDownValueChanged('NoneEvent');
            % Discover available tracks and set default
            tracks                          = listItemsInFolder('inc/pathGeneration', 'track_');
            app.TrackDropDown.Items         = stripExtensions(tracks,'.m');
            app.TrackDropDown.set('Value',  app.TrackDropDown.Items{1});
            app.TrackDropDownValueChanged('NoneEvent');
            % Initialize parameter multiplier slider
            app.ParameterMultiplierSlider.Limits    = [0.1,3.0];
            app.ParameterMultiplierSlider.Value     = 1.0;
            % Initialize optimization options
            app.optimizationOptions         = optimizerSetup({});
            % Initialize evaluator settings
            app.evaluatorSettings           = evaluatorSetup({});
            app.localEvaluatorSettings      = evaluatorSetup({});
            % Toggle sectionwise settings
            app.toggleSectionwiseSettings();
            
            %% END INITIALIZATION -------------------------------------------------------------
            app.activeAxes  = app.UIAxes;
            app.plotBlock   = false;
            
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
            % Load selected vehicle data
            vehicleName     = app.VehicleDropDown.Value;
            app.loadVehicleData(vehicleName);
            % Update plot
            app.PlotVehicleDataButtonGroupSelectionChanged('NoneEvent');
            % Update evaluator
            app.setCommonEvaluatorProperties(   'maxVelocityFunction',app.vehicleModel.maxCorneringVelocity.method,     ...
                                                'maxAccelerationFunction',app.vehicleModel.maxAcceleration.method,      ...
                                                'maxDecelerationFunction',app.vehicleModel.maxDeceleration.method );
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            %app.VehicleDropDown.
            % Change active axes to the UIAxes in the current tab
            for k=1:length(selectedTab.Children)
                if strcmp( class(selectedTab.Children(k)), class(app.UIAxes) )
                    % Set active axes
                    app.activeAxes = selectedTab.Children(k);
                    break;
                end
            end
            
            
        end

        % Selection changed function: PlotVehicleDataButtonGroup
        function PlotVehicleDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotVehicleDataButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Maximum Acceleration'
                    data = app.vehicleModel.maxAcceleration.data;
                    xlabel( app.activeAxes, 'velocity [m/s]')
                    ylabel( app.activeAxes, 'acceleration [m/s^2]' )
                case 'Maximum Deceleration'
                    data = app.vehicleModel.maxDeceleration.data;
                    xlabel( app.activeAxes, 'velocity [m/s]')
                    ylabel( app.activeAxes, 'acceleration [m/s^2]' )
                case 'Maximum Cornering Velocity'
                    data = app.vehicleModel.maxCorneringVelocity.data;
                    xlabel( app.activeAxes, 'curvature [1]')
                    ylabel( app.activeAxes, 'velocity [m/s]' )
                otherwise
                    error('Invalid button text, plase update the app')
            end
            
            % Plot data
            app.plot(   data(:,1),       ...
                        data(:,2) );
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            value                           = app.ObjectiveDropDown.Value;
            app.objectiveFunction           = eval( ['@', value] );
            % Update evaluator
            app.setCommonEvaluatorProperties('targetFunction',app.objectiveFunction)
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            value                               = app.TrackDropDown.Value;
            app.track = eval( [ value, '()'] );
            app.ParameterMultiplierSlider.Value = 1.0;
            app.ParameterCountLabel.Text        = num2str(length(app.track.bound1));
            app.plotFunction( @(ax)plotTrackOnAxes(ax,app.track), 'axis equal' );
        end

        % Button pushed function: ConfigureOptimizerButton
        function ConfigureOptimizerButtonPushed(app, event)
            app.optimizationOptions = optimizerSetup(app.optimizationOptions);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if strcmp(app.RunButton.Text,'Run')
                app.terminateOptimization   = false;
                app.RunButton.Text          = 'Stop';
                app.setCommonEvaluatorProperties('norm',1.0);
                if ~app.SectionwiseopimizationCheckBox.Value
                    [app.params,app.track] =                                ...
                    optimizePath(           'track',        app.track,      ...
                                            'evaluator',    app.evaluator,  ...
                                            'axesHandle',   app.activeAxes, ...
                                            'optimoptions', parseOptimizationOptions(app.optimizationOptions)   );
                else
                    [app.params,app.track] =                                                        ...
                    optimizePathIterative(  app.evaluator,app.localEvaluator,app.sectionRefinement, ...
                                            'track',        app.track,                              ...
                                            'axesHandle',   app.activeAxes,                         ...
                                            'optimoptions', parseOptimizationOptions(app.optimizationOptions)   );
                end
                app.RunButton.Text          = 'Run';
            else
                app.terminateOptimization   = true;
                app.RunButton.Text          = 'Run';
            end

            app.runPostProcessor();
        end

        % Button pushed function: ConfigureEvaluatorButton
        function ConfigureEvaluatorButtonPushed(app, event)
            app.evaluatorSettings = evaluatorSetup(app.evaluatorSettings);
            for k=1:length(app.evaluatorSettings)
                app.evaluator.(app.evaluatorSettings{k,1}) = str2double(app.evaluatorSettings{k,2});
            end
        end

        % Value changed function: ParameterMultiplierSlider
        function ParameterMultiplierSliderValueChanged(app, event)
            value                           = app.ParameterMultiplierSlider.Value;
            trackName                       = app.TrackDropDown.Value;
            app.track                       = eval([trackName,'(',num2str(value),')']);
            app.ParameterCountLabel.Text    = num2str(length(app.track.bound1));
            app.plotFunction( @(ax)plotTrackOnAxes(ax,app.track), 'axis equal' );
        end

        % Button pushed function: ConfigureLocalEvaluatorButton
        function ConfigureLocalEvaluatorButtonPushed(app, event)
            app.localEvaluatorSettings = evaluatorSetup(app.localEvaluatorSettings);
            for k=1:length(app.evaluatorSettings)
                app.localEvaluator.(app.localEvaluatorSettings{k,1}) = str2double(app.localEvaluatorSettings{k,2});
            end
        end

        % Value changed function: SectionRefinementSlider
        function SectionRefinementSliderValueChanged(app, event)
            value = app.SectionRefinementSlider.Value;
            app.sectionRefinement           = value;
            app.sectionRefinementLabel.Text = num2str(value);
        end

        % Value changed function: SectionwiseopimizationCheckBox
        function SectionwiseopimizationCheckBoxValueChanged(app, event)
            app.toggleSectionwiseSettings();
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            set_parm(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % function to run the simulation depending on the user choice
            switch app.SimulationOptionsDropDown.Value
                case 'XY Plotter (2D)'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value,app.FrontTireCornerStifnessEditField.Value, ...
                    app.RearTireCornerStifnessEditField.Value,app.CoefficientofFrictionEditField.Value, app.SteeringAgressivenessEditField.Value, ...
                    app.MaxsteeringangleradEditField.Value, app.MinsteeringangleradEditField.Value, app.PredictionHorizonstepsEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')
                set_param('MPC_Controller/Visualization/UE','commented','on')
                controller_run()
                case 'Unreal Engine (3D)'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')
                set_param('MPC_Controller/Visualization/Visualization','commented','on')
                controller_run()
                case 'Both'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')                
                controller_run()
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            % Function to start the optimization process
            set_parm(app)
            [mass_check,wheelbase_check,cog_check,h_check,f_tire_check,r_tire_check,mu_check,agr_check,min_check,max_check,pred_check] = parameters(app);
            [p,info]=parameterEstimation_app(mass_check,wheelbase_check,cog_check,h_check,f_tire_check,r_tire_check,mu_check,agr_check,min_check,            ...
            max_check,pred_check, app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value,app.CenterofGravitylocationfromthefrontaxleEditField.Value, ...
            app.AxleHeightEditField.Value, app.FrontTireCornerStifnessEditField.Value, app.RearTireCornerStifnessEditField.Value,      ...
            app.CoefficientofFrictionEditField.Value, app.SteeringAgressivenessEditField.Value, app.MaxsteeringangleradEditField.Value, ...
            app.MinsteeringangleradEditField.Value, app.PredictionHorizonstepsEditField.Value, ...                                                                                                                       
            app.MassRangeEditField.Value,app.MassRangeEditField_max.Value,app.WheelbaseRangeEditField.Value, ...
            app.WheelbaseRangeEditField_max.Value,app.COGRangeEditField.Value, app.COGRangeEditField_max.Value,app.AxleHeightRangeEditField.Value, ...
            app.AxleHeightRangeEditField_max.Value, app.FrontTireCornerStiffness_max.Value, app.FrontTireCornerStiffness_min.Value, ...
            app.RearTireCornerStiffness_max.Value, app.RearTireCornerStiffness_min.Value, app.CoefficientofFriction_max.Value, app.CoefficientofFriction_min.Value, ...
            app.SteeringAgressiveness_max.Value, app.SteeringAgressiveness_min.Value, app.Min_steering_max.Value, app.Min_steering_min.Value, ...
            app.Max_steering_max.Value, app.Max_steering_min.Value, app.Prediction_Horizon_max.Value, app.Prediction_Horizon_min.Value);
     
            for i=1:size(p,1)
                % Display the optimization results
                if (p(i,1).Name=="dfront")
                  app.COGEditField.Value= p(i,1).Value;
                elseif (p(i,1).Name=="h")
                  app.AxleHeightEditField_2.Value=p(i,1).Value;
                elseif (p(i,1).Name=="mass")
                  app.MassEditField.Value= p(i,1).Value;
                elseif (p(i,1).Name=="wheelbase")
                  app.WheelbaseEditField.Value= p(i,1).Value;
                elseif (p(i,1).Name=="f_tire_stiff")
                  app.FrontTireCornerStiffness_result.Value=p(i,1).Value;
                elseif (p(i,1).Name=="r_tire_stiff")
                  app.RearTireCornerStiffness_result.Value= p(i,1).Value;
                elseif (p(i,1).Name=="mu")
                  app.CoefficientofFriction_result.Value= p(i,1).Value;                  
                elseif (p(i,1).Name=="aggressive")
                  app.SteeringAgressiveness_result.Value=p(i,1).Value;
                elseif (p(i,1).Name=="min_steer")
                  app.Min_steering_result.Value= p(i,1).Value;
                elseif (p(i,1).Name=="max_steer")
                  app.Max_steering_result.Value= p(i,1).Value;   
                elseif (p(i,1).Name=="pred_Horizon")
                    app.Prediction_Horizon_result.Value= p(i,1).Value;
                end                     
            end   
            
        end

        % Close request function: PathOptimizationAppUIFigure
        function PathOptimizationAppUIFigureCloseRequest(app, event)
            delete(app)
            evalin('base','clear app');
        end

        % Selection changed function: 
        % DoyouwanttochoosevehiclecontrollerparametersButtonGroup
        function DoyouwanttochoosevehiclecontrollerparametersButtonGroupSelectionChanged(app, event)
           on_off(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PathOptimizationAppUIFigure = uifigure('Visible', 'off');
            app.PathOptimizationAppUIFigure.Name = 'Path Optimization App';
            app.PathOptimizationAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @PathOptimizationAppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PathOptimizationAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ModelSetupTab = uitab(app.TabGroup);
            app.ModelSetupTab.Title = 'Model Setup';
            app.VehicleDropDownLabel = uilabel(app.ModelSetupTab);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.ModelSetupTab);
            app.VehicleDropDown.Items = {};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = {};
            app.UIAxes = uiaxes(app.ModelSetupTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlotVehicleDataButtonGroup = uibuttongroup(app.ModelSetupTab);
            app.PlotVehicleDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotVehicleDataButtonGroupSelectionChanged, true);
            app.PlotVehicleDataButtonGroup.Title = 'Plot Vehicle Data';
            app.MaximumAccelerationButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumAccelerationButton.Text = 'Maximum Acceleration';
            app.MaximumAccelerationButton.Value = true;
            app.MaximumDecelerationButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumDecelerationButton.Text = 'Maximum Deceleration';
            app.MaximumCorneringVelocityButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumCorneringVelocityButton.Text = 'Maximum Cornering Velocity';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ObjectiveDropDownLabel = uilabel(app.OptimizationTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.OptimizationTab);
            app.ObjectiveDropDown.Items = {};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Value = {};
            app.TrackDropDownLabel = uilabel(app.OptimizationTab);
            app.TrackDropDownLabel.Text = 'Track';
            app.TrackDropDown = uidropdown(app.OptimizationTab);
            app.TrackDropDown.Items = {};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Value = {};
            app.UIAxes2 = uiaxes(app.OptimizationTab);
            title(app.UIAxes2, 'Track')
            xlabel(app.UIAxes2, 'x [m]')
            ylabel(app.UIAxes2, 'y [m]')
            app.ConfigureOptimizerButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureOptimizerButtonPushed, true);
            app.ConfigureOptimizerButton.Text = 'Configure Optimizer';
            app.SectionwiseopimizationCheckBox = uicheckbox(app.OptimizationTab);
            app.SectionwiseopimizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SectionwiseopimizationCheckBoxValueChanged, true);
            app.SectionwiseopimizationCheckBox.Text = 'Sectionwise opimization';
            app.RunButton = uibutton(app.OptimizationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConfigureEvaluatorButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureEvaluatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureEvaluatorButtonPushed, true);
            app.ConfigureEvaluatorButton.Text = 'Configure Evaluator';
            app.ParameterMultiplierLabel = uilabel(app.OptimizationTab);
            app.ParameterMultiplierLabel.Text = {'Parameter'; 'Multiplier'};
            app.ParameterMultiplierSlider = uislider(app.OptimizationTab);
            app.ParameterMultiplierSlider.Limits = [0.1 3];
            app.ParameterMultiplierSlider.MajorTicks = [0.1 1 2 3];
            app.ParameterMultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @ParameterMultiplierSliderValueChanged, true);
            app.ParameterMultiplierSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.ParameterMultiplierSlider.Value = 1;
            app.NumberofParametersLabel = uilabel(app.OptimizationTab);
            app.NumberofParametersLabel.Text = 'Number of Parameters: ';
            app.ParameterCountLabel = uilabel(app.OptimizationTab);
            app.ParameterCountLabel.Text = '#';
            app.ConfigureLocalEvaluatorButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureLocalEvaluatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureLocalEvaluatorButtonPushed, true);
            app.ConfigureLocalEvaluatorButton.Text = 'Configure Local Evaluator';
            app.SectionRefinementSliderLabel = uilabel(app.OptimizationTab);
            app.SectionRefinementSliderLabel.Text = {'Section'; 'Refinement'};
            app.SectionRefinementSlider = uislider(app.OptimizationTab);
            app.SectionRefinementSlider.Limits = [1 5];
            app.SectionRefinementSlider.MajorTicks = [1 2 3 4 5];
            app.SectionRefinementSlider.ValueChangedFcn = createCallbackFcn(app, @SectionRefinementSliderValueChanged, true);
            app.SectionRefinementSlider.Value = 2;
            app.sectionRefinementLabel = uilabel(app.OptimizationTab);
            app.sectionRefinementLabel.Text = '#';
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.VehicleParametersTab = uitab(app.TabGroup);
            app.VehicleParametersTab.Title = 'Vehicle Parameters';
            app.DoyouwanttochoosevehiclecontrollerparametersButtonGroup = uibuttongroup(app.VehicleParametersTab);
            app.DoyouwanttochoosevehiclecontrollerparametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DoyouwanttochoosevehiclecontrollerparametersButtonGroupSelectionChanged, true);
            app.DoyouwanttochoosevehiclecontrollerparametersButtonGroup.Title = 'Do you want to choose vehicle/controller parameter(s)?';
            app.YesButton = uiradiobutton(app.DoyouwanttochoosevehiclecontrollerparametersButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoUsethedefaultparametersButton = uiradiobutton(app.DoyouwanttochoosevehiclecontrollerparametersButtonGroup);
            app.NoUsethedefaultparametersButton.Text = 'No (Use the default parameter(s))';
            app.EntertheParametersLabel = uilabel(app.VehicleParametersTab);
            app.EntertheParametersLabel.Text = 'Enter the Parameter(s):';
            app.EntertheParametersLabel_2 = uilabel(app.VehicleParametersTab);
            app.EntertheParametersLabel_2.Text = 'Note: Choosing unsuitable parameters may lead to undesired simulation';
            app.SubmitButton = uibutton(app.VehicleParametersTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.VehicleMassEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.VehicleMassEditFieldLabel.Text = 'Vehicle Mass';
            app.VehicleMassEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.VehicleWheelbaseEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.VehicleWheelbaseEditFieldLabel.Text = 'Vehicle Wheelbase';
            app.VehicleWheelbaseEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.AxleHeightEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.AxleHeightEditFieldLabel.Text = 'Axle Height';
            app.AxleHeightEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.CenterofGravitylocationfromthefrontaxleEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Text = 'Center of Gravity location from the front axle';
            app.CenterofGravitylocationfromthefrontaxleEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.FrontTireCornerStifnessEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.FrontTireCornerStifnessEditFieldLabel.Text = 'Front Tire Corner Stifness';
            app.FrontTireCornerStifnessEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.RearTireCornerStifnessEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.RearTireCornerStifnessEditFieldLabel.Text = 'Rear Tire Corner Stifness';
            app.RearTireCornerStifnessEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.CoefficientofFrictionEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.CoefficientofFrictionEditFieldLabel.Text = 'Coefficient of Friction';
            app.CoefficientofFrictionEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.SteeringAgressivenessEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.SteeringAgressivenessEditFieldLabel.Text = 'Steering Agressiveness ';
            app.SteeringAgressivenessEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.MaxsteeringangleradEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.MaxsteeringangleradEditFieldLabel.Text = 'Max steering angle (rad)';
            app.MaxsteeringangleradEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.MinsteeringangleradEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.MinsteeringangleradEditFieldLabel.Text = 'Min steering angle (rad)';
            app.MinsteeringangleradEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.PredictionHorizonstepsEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.PredictionHorizonstepsEditFieldLabel.Text = 'Prediction Horizon (steps)';
            app.PredictionHorizonstepsEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.PleasechoosehowdoyouwanttosimulatetheresultsLabel = uilabel(app.SimulationTab);
            app.PleasechoosehowdoyouwanttosimulatetheresultsLabel.Text = 'Please choose how do you want to simulate the results:';
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SimulationOptionsDropDownLabel = uilabel(app.SimulationTab);
            app.SimulationOptionsDropDownLabel.Text = 'Simulation Options';
            app.SimulationOptionsDropDown = uidropdown(app.SimulationTab);
            app.SimulationOptionsDropDown.Items = {'XY Plotter (2D)', 'Unreal Engine (3D)', 'Both'};
            app.SimulationOptionsDropDown.Value = 'XY Plotter (2D)';
            app.ParametersEstimationTab = uitab(app.TabGroup);
            app.ParametersEstimationTab.Title = 'Parameters Estimation';
            app.WhichParametersdoyouwanttooptimizeLabel = uilabel(app.ParametersEstimationTab);
            app.WhichParametersdoyouwanttooptimizeLabel.Text = 'Which Parameter(s) do you want to optimize?';
            app.VehicleMassCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.VehicleMassCheckBox.Text = 'Vehicle Mass';
            app.VehicleWheelbaseCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.VehicleWheelbaseCheckBox.Text = 'Vehicle Wheelbase';
            app.LocationofcenterofgravityCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.LocationofcenterofgravityCheckBox.Text = 'Location of center of gravity ';
            app.AxleHeightCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.AxleHeightCheckBox.Text = 'Axle Height';
            app.StartButton_2 = uibutton(app.ParametersEstimationTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.MassRangeEditField_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label = uilabel(app.ParametersEstimationTab);
            app.Label.Text = '-';
            app.WheelbaseRangeEditField_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_2 = uilabel(app.ParametersEstimationTab);
            app.Label_2.Text = '-';
            app.COGRangeEditField_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_3 = uilabel(app.ParametersEstimationTab);
            app.Label_3.Text = '-';
            app.AxleHeightRangeEditField_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_4 = uilabel(app.ParametersEstimationTab);
            app.Label_4.Text = '-';
            app.MassRangeEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.WheelbaseRangeEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.COGRangeEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.AxleHeightRangeEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.ParametersLabel = uilabel(app.ParametersEstimationTab);
            app.ParametersLabel.Text = 'Parameters';
            app.FrontTireCornerStiffnessCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.FrontTireCornerStiffnessCheckBox.Text = 'Front Tire Corner Stiffness';
            app.RearTireCornerStiffnessCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.RearTireCornerStiffnessCheckBox.Text = 'Rear Tire Corner Stiffness';
            app.CoefficientofFrictionCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.CoefficientofFrictionCheckBox.Text = 'Coefficient of Friction';
            app.SteeringAgressivenessCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.SteeringAgressivenessCheckBox.Text = 'Steering Agressiveness ';
            app.FrontTireCornerStiffness_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_5 = uilabel(app.ParametersEstimationTab);
            app.Label_5.Text = '-';
            app.RearTireCornerStiffness_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_6 = uilabel(app.ParametersEstimationTab);
            app.Label_6.Text = '-';
            app.CoefficientofFriction_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_7 = uilabel(app.ParametersEstimationTab);
            app.Label_7.Text = '-';
            app.SteeringAgressiveness_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_8 = uilabel(app.ParametersEstimationTab);
            app.Label_8.Text = '-';
            app.FrontTireCornerStiffness_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.RearTireCornerStiffness_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.CoefficientofFriction_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.SteeringAgressiveness_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.RangeLabel = uilabel(app.ParametersEstimationTab);
            app.RangeLabel.Text = 'Range';
            app.MaxsteeringangleradCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.MaxsteeringangleradCheckBox.Text = 'Max steering angle (rad)';
            app.MinsteeringangleradCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.MinsteeringangleradCheckBox.Text = 'Min steering angle (rad)';
            app.PredictionHorizonstepsCheckBox = uicheckbox(app.ParametersEstimationTab);
            app.PredictionHorizonstepsCheckBox.Text = 'Prediction Horizon (steps)';
            app.Max_steering_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_9 = uilabel(app.ParametersEstimationTab);
            app.Label_9.Text = '-';
            app.Min_steering_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_10 = uilabel(app.ParametersEstimationTab);
            app.Label_10.Text = '-';
            app.Prediction_Horizon_max = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Label_11 = uilabel(app.ParametersEstimationTab);
            app.Label_11.Text = '-';
            app.Max_steering_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Min_steering_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Prediction_Horizon_min = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.MassEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.WheelbaseEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.COGEditField = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.AxleHeightEditField_2 = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.FrontTireCornerStiffness_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.RearTireCornerStiffness_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.CoefficientofFriction_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.SteeringAgressiveness_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Max_steering_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Min_steering_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.Prediction_Horizon_result = uieditfield(app.ParametersEstimationTab, 'numeric');
            app.ResultsLabel = uilabel(app.ParametersEstimationTab);
            app.ResultsLabel.Text = 'Results';
            % Show the figure after all components are created
            app.PathOptimizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathOptimizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PathOptimizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PathOptimizationAppUIFigure)
        end
    end
end