classdef PathOptimizationApp_X < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PathOptimizationAppUIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ModelSetupTab                   matlab.ui.container.Tab
        VehicleDropDownLabel            matlab.ui.control.Label
        VehicleDropDown                 matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        PlotVehicleDataButtonGroup      matlab.ui.container.ButtonGroup
        MaximumAccelerationButton       matlab.ui.control.RadioButton
        MaximumDecelerationButton       matlab.ui.control.RadioButton
        MaximumCorneringVelocityButton  matlab.ui.control.RadioButton
        RoadandTireConditionButtonGroup  matlab.ui.container.ButtonGroup
        ModerateButton_2                matlab.ui.control.RadioButton
        WetButton                       matlab.ui.control.RadioButton
        DryButton                       matlab.ui.control.RadioButton
        OptimizationTab                 matlab.ui.container.Tab
        ObjectiveDropDownLabel          matlab.ui.control.Label
        ObjectiveDropDown               matlab.ui.control.DropDown
        TrackDropDownLabel              matlab.ui.control.Label
        TrackDropDown                   matlab.ui.control.DropDown
        UIAxes2                         matlab.ui.control.UIAxes
        ConfigureOptimizerButton        matlab.ui.control.Button
        SectionwiseopimizationCheckBox  matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        ConfigureEvaluatorButton        matlab.ui.control.Button
        ParameterMultiplierLabel        matlab.ui.control.Label
        ParameterMultiplierSlider       matlab.ui.control.Slider
        NumberofParametersLabel         matlab.ui.control.Label
        ParameterCountLabel             matlab.ui.control.Label
        ConfigureLocalEvaluatorButton   matlab.ui.control.Button
        SectionRefinementSliderLabel    matlab.ui.control.Label
        SectionRefinementSlider         matlab.ui.control.Slider
        sectionRefinementLabel          matlab.ui.control.Label
        OptimizationResultsTab          matlab.ui.container.Tab
        VehicleParametersTab            matlab.ui.container.Tab
        DoyouwanttochoosevehicleparametersButtonGroup  matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoUsethedefaultparametersButton  matlab.ui.control.RadioButton
        EntertheParametersLabel         matlab.ui.control.Label
        EntertheParametersLabel_2       matlab.ui.control.Label
        SubmitButton                    matlab.ui.control.Button
        VehicleMassEditFieldLabel       matlab.ui.control.Label
        VehicleMassEditField            matlab.ui.control.NumericEditField
        VehicleWheelbaseEditFieldLabel  matlab.ui.control.Label
        VehicleWheelbaseEditField       matlab.ui.control.NumericEditField
        AxleHeightEditFieldLabel        matlab.ui.control.Label
        AxleHeightEditField             matlab.ui.control.NumericEditField
        CenterofGravitylocationfromthefrontaxleEditFieldLabel  matlab.ui.control.Label
        CenterofGravitylocationfromthefrontaxleEditField  matlab.ui.control.NumericEditField
        SimulationTab                   matlab.ui.container.Tab
        PleasechoosehowdoyouwanttosimulatetheresultsLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        SimulationOptionsDropDownLabel  matlab.ui.control.Label
        SimulationOptionsDropDown       matlab.ui.control.DropDown
        ParameterEstimationTab          matlab.ui.container.Tab
        WhichParametersdoyouwanttooptimizeLabel  matlab.ui.control.Label
        VehicleMassCheckBox             matlab.ui.control.CheckBox
        VehicleWheelbaseCheckBox        matlab.ui.control.CheckBox
        LocationofcenterofgravityCheckBox  matlab.ui.control.CheckBox
        AxleHeightCheckBox              matlab.ui.control.CheckBox
        StartButton_2                   matlab.ui.control.Button
        MassRangeEditField_max          matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        WheelbaseRangeEditField_max     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        COGRangeEditField_max           matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        AxleHeightRangeEditField_max    matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        MassRangeEditFieldLabel         matlab.ui.control.Label
        MassRangeEditField              matlab.ui.control.NumericEditField
        WheelbaseRangeEditFieldLabel    matlab.ui.control.Label
        WheelbaseRangeEditField         matlab.ui.control.NumericEditField
        COGRangeEditFieldLabel          matlab.ui.control.Label
        COGRangeEditField               matlab.ui.control.NumericEditField
        AxleHeightRangeEditFieldLabel   matlab.ui.control.Label
        AxleHeightRangeEditField        matlab.ui.control.NumericEditField
        ResultsLabel                    matlab.ui.control.Label
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        WheelbaseEditFieldLabel         matlab.ui.control.Label
        WheelbaseEditField              matlab.ui.control.NumericEditField
        COGEditFieldLabel               matlab.ui.control.Label
        COGEditField                    matlab.ui.control.NumericEditField
        AxleHeightEditField_2Label      matlab.ui.control.Label
        AxleHeightEditField_2           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        vehicleModel                    % Structure with 3 lookups:
                                        % - maxAcceleration
                                        % - maxDeceleration
                                        % - maxCorneringVelocity
        objectiveFunction               % Objective function used in the optimization
        track                           % Track to generate the path on
        evaluator                       % Evaluator instance used in the optimization 
                                        %   (see the documentation)
        evaluatorSettings               % Store user settable evaluator parameters
        terminateOptimization           % The optimizer checks this variable after each iteration
                                        % and stops if it's true
        localEvaluator                  % Evaluator for sectionwise optimization
        localEvaluatorSettings          % Store user settable evaluator parameters for sectionwise optimization
        sectionRefinement               % Parameter multiplier for sectionwise optimization
        params                          % Optimization target parameters
        mu                              % Friction coefficient for Road-Tire condition
        vehicleName                     % Vehicle Model selected
        vehicleString                   % String of vehicle name
    end
    
    properties (Access = private)
        activeAxes                      % Handle of the currently displayed axes
        plotBlock                       % Local plot blocking flag
        optimizationOptions             % Subset of optimoptions('fmincon') that can be 
                                        % modified by the user
    end
    
    methods (Access = private)
        
        % Load vehicle data from inc/dynamics/vehicles/vehicleName
        function loadVehicleData(app, vehicleName)
            app.vehicleModel.maxAcceleration =                  ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/accelerationTable.csv'],      ...
                                'linearSearch');
            app.vehicleModel.maxDeceleration =                  ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/decelerationTable.csv'],      ...
                                'linearSearch');
            app.vehicleModel.maxCorneringVelocity =             ...
                TableLookup(    ['inc/dynamics/vehicles/',      ...
                                vehicleName,                    ...
                                '/corneringVelocityTable.csv'], ...
                                'linearSearch');
        end
        
        % Plot arguments in the active axes
        function plot(app, x, y, varargin)
            if ~app.plotBlock
                cla(app.activeAxes);
                plot( app.activeAxes, x, y, varargin{:} );
                app.formatAxes();
            end
        end
        
        % Call plotter function in the active axes
        function plotFunction(app,func,varargin)
            if ~app.plotBlock
                cla(app.activeAxes);
                func(app.activeAxes);
                app.formatAxes(varargin{:});
            end
        end
        
        function formatAxes(app,varargin)
            if ~app.plotBlock
                % Set axis tick precision
                xtickformat(app.activeAxes,'%3d')
                ytickformat(app.activeAxes,'%3d')
                % Get axes ranges
                rng = [Inf,-Inf,Inf,-Inf];
                for k=1:length(app.activeAxes.Children)
                    rng = [ min( rng(1), min( app.activeAxes.Children(k).XData ) ), ...
                            max( rng(2), max( app.activeAxes.Children(k).XData ) ), ...
                            min( rng(3), min( app.activeAxes.Children(k).YData ) ), ...
                            max( rng(4), max( app.activeAxes.Children(k).YData ) ) ];
                end
                % Apply axes ranges
                axis(app.activeAxes, rng);
                % Set axes equal if requested
                if any( strcmp(varargin, 'axis equal') )
                    axis(app.activeAxes,'equal');
                end
                % Set aspect ratio
                pbaspect(app.activeAxes, [1.0,1.0,1.0]);
            end
        end
        
        
        function toggleSectionwiseSettings(app)
            if app.SectionwiseopimizationCheckBox.Value
                app.ConfigureLocalEvaluatorButton.Visible   = true;
                app.SectionRefinementSlider.Visible         = true;
                app.SectionRefinementSliderLabel.Visible    = true;
                app.sectionRefinementLabel.Visible          = true;
            else
                app.ConfigureLocalEvaluatorButton.Visible   = false;
                app.SectionRefinementSlider.Visible         = false;
                app.SectionRefinementSliderLabel.Visible    = false;
                app.sectionRefinementLabel.Visible          = false;
            end
        end
        
        
        function setCommonEvaluatorProperties(app,varargin)
            for k=1:2:length(varargin)
                app.evaluator.(varargin{k})         = varargin{k+1};
                app.localEvaluator.(varargin{k})    = varargin{k+1};
            end
        end


        function runPostProcessor(app)
            % Select the evaluator
            evaluator = app.evaluator;
            if (app.SectionwiseopimizationCheckBox.Value)
                evaluator = app.localEvaluator;
            end
            % Configure evaluator
            evaluator.writeDetails  = true;
            evaluator.optimRange    = 0;
            % Run evaluation one last time to get the data
            evaluatePath(app.track,app.params,evaluator);
            % Run postprocessing
            if ~isempty(evaluator.velocities)
                app.plotFunction( @(ax) plotSolutionOnAxes(ax, app.track, app.params, evaluator) );
                plotEvaluatorOnAxes( app.OptimizationResultsTab, evaluator );
            end
            
        end
        
        
        % ------------------------------------------------------------------
        function [mass_check,wheelbase_check,cog_check,h_check] = parameters(app)
            % function to check which parameter(s) is/are selected for
            % optimization
            if app.VehicleMassCheckBox.Value
                mass_check='mass';
            else
                mass_check='';
            end
            if app.VehicleWheelbaseCheckBox.Value
                wheelbase_check='wheelbase';
            else
                wheelbase_check='';
            end
            if app.LocationofcenterofgravityCheckBox.Value
                cog_check='dfront';
            else
                cog_check='';
            end
            if app.AxleHeightCheckBox.Value
                h_check='h';
            else
                h_check='';
            end   
        end
        
        function on_off(app)
            % Function to switch the Edit Fields on and off depending on
            % whether the user wants to select the parameters or not
            if app.YesButton.Value
                app.VehicleMassEditField.Visible=true;
                app.AxleHeightEditField.Visible=true;
                app.CenterofGravitylocationfromthefrontaxleEditField.Visible=true;
                app.VehicleWheelbaseEditField.Visible=true;
                app.EntertheParametersLabel.Visible=true;
                app.EntertheParametersLabel_2.Visible=true;
                app.VehicleMassEditFieldLabel.Visible=true;
                app.AxleHeightEditFieldLabel.Visible=true;
                app.VehicleWheelbaseEditFieldLabel.Visible=true;
                app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Visible=true;
                app.MassRangeEditField.Visible=true;
                app.MassRangeEditField_max.Visible=true;
                app.Label.Visible=true;
                app.WheelbaseRangeEditField_max.Visible=true;
                app.Label_2.Visible=true;
                app.WheelbaseRangeEditField.Visible=true;
                app.COGRangeEditField_max.Visible=true;
                app.Label_3.Visible=true;
                app.COGRangeEditField.Visible=true;
                app.AxleHeightRangeEditField_max.Visible=true;
                app.Label_4.Visible=true;
                app.AxleHeightRangeEditField.Visible=true;
                app.MassRangeEditFieldLabel.Visible=true;
                app.AxleHeightRangeEditFieldLabel.Visible=true;
                app.COGRangeEditFieldLabel.Visible=true;
                app.WheelbaseRangeEditFieldLabel.Visible=true;
                
            else
                app.VehicleMassEditField.Visible=false;
                app.AxleHeightEditField.Visible=false;
                app.CenterofGravitylocationfromthefrontaxleEditField.Visible=false;
                app.VehicleWheelbaseEditField.Visible=false;
                app.EntertheParametersLabel.Visible=false;
                app.EntertheParametersLabel_2.Visible=false;   
                app.VehicleMassEditFieldLabel.Visible=false;
                app.AxleHeightEditFieldLabel.Visible=false;
                app.VehicleWheelbaseEditFieldLabel.Visible=false;
                app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Visible=false;    
                app.MassRangeEditField.Visible=false;
                app.MassRangeEditField_max.Visible=false;
                app.Label.Visible=false;
                app.WheelbaseRangeEditField_max.Visible=false;
                app.Label_2.Visible=false;
                app.WheelbaseRangeEditField.Visible=false;
                app.COGRangeEditField_max.Visible=false;
                app.Label_3.Visible=false;
                app.COGRangeEditField.Visible=false;
                app.AxleHeightRangeEditField_max.Visible=false;
                app.Label_4.Visible=false;
                app.AxleHeightRangeEditField.Visible=false;
                app.MassRangeEditFieldLabel.Visible=false;
                app.AxleHeightRangeEditFieldLabel.Visible=false;
                app.COGRangeEditFieldLabel.Visible=false;
                app.WheelbaseRangeEditFieldLabel.Visible=false;
            end            
        end
        
        function set_parm(app)
            % Funtion to set the parameters/limits to the default values
            if ~app.YesButton.Value
                app.VehicleMassEditField.Value=1575;
                app.VehicleWheelbaseEditField.Value=2.8;
                app.CenterofGravitylocationfromthefrontaxleEditField.Value=1.2;
                app.AxleHeightEditField.Value=0.35;    
                app.MassRangeEditField.Value=700;
                app.MassRangeEditField_max.Value=1700;
                app.WheelbaseRangeEditField.Value=2.5;
                app.WheelbaseRangeEditField_max.Value=6;
                app.COGRangeEditField.Value=1;
                app.COGRangeEditField_max.Value=1.5;
                app.AxleHeightRangeEditField.Value=0.135;
                app.AxleHeightRangeEditField_max.Value=0.36;   
            end            
        end
        % ------------------------------------------------------------------


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set MATLAB path
            init();
            % Set plot block
            app.plotBlock   = false;
            
            % Initialize global variables
            app.terminateOptimization   = false;
            
            % Prevent figure resizing (make subplot work in the app)
            app.OptimizationResultsTab.AutoResizeChildren = 'off';
            
            %% INITIALIZE MODEL SETUP TAB -----------------------------------------------------
            % Set active axes
            app.activeAxes  = app.UIAxes;
            % Initialize evaluator
            app.evaluator       = Evaluator();
            app.localEvaluator  = Evaluator();
            % Discover available vehicle models and set default
            app.VehicleDropDown.Items       = listItemsInFolder('inc/dynamics/vehicles');
            app.VehicleDropDown.set('Value',    app.VehicleDropDown.Items{1});
            app.VehicleDropDownValueChanged('NoneEvent');
            
            %% INITIALIZE OPTIMIZATION TAB ----------------------------------------------------
            % Set active axes
            app.activeAxes  = app.UIAxes2;
            % Discover available objective functions and set default
            objectives                      = listItemsInFolder('inc/pathOptimization', 'objective_');
            app.ObjectiveDropDown.Items     = stripExtensions(objectives,'.m');
            app.ObjectiveDropDown.set('Value',  app.ObjectiveDropDown.Items{1});
            app.ObjectiveDropDownValueChanged('NoneEvent');
            % Discover available tracks and set default
            tracks                          = listItemsInFolder('inc/pathGeneration', 'track_');
            app.TrackDropDown.Items         = stripExtensions(tracks,'.m');
            app.TrackDropDown.set('Value',  app.TrackDropDown.Items{1});
            app.TrackDropDownValueChanged('NoneEvent');
            % Initialize parameter multiplier slider
            app.ParameterMultiplierSlider.Limits    = [0.1,3.0];
            app.ParameterMultiplierSlider.Value     = 1.0;
            % Initialize optimization options
            app.optimizationOptions         = optimizerSetup({});
            % Initialize evaluator settings
            app.evaluatorSettings           = evaluatorSetup({});
            app.localEvaluatorSettings      = evaluatorSetup({});
            % Toggle sectionwise settings
            app.toggleSectionwiseSettings();
            
            %% END INITIALIZATION -------------------------------------------------------------
            app.activeAxes  = app.UIAxes;
            app.plotBlock   = false;
            
        end

        % Value changed function: VehicleDropDown
        function VehicleDropDownValueChanged(app, event)
            % Load selected vehicle data
            app.vehicleName     = app.VehicleDropDown.Value;
            app.loadVehicleData(app.vehicleName);
            % Update plot
            app.PlotVehicleDataButtonGroupSelectionChanged('NoneEvent');
            % Update evaluator
            app.setCommonEvaluatorProperties(   'maxVelocityFunction',app.vehicleModel.maxCorneringVelocity.method,     ...
                                                'maxAccelerationFunction',app.vehicleModel.maxAcceleration.method,      ...
                                                'maxDecelerationFunction',app.vehicleModel.maxDeceleration.method );
            
            
            if strcmp (app.vehicleName,'SimulinkModel')||strcmp(app.vehicleName,'Simulink3DOF')
                app.RoadandTireConditionButtonGroup.Visible = 1;
                
            else 
                app.RoadandTireConditionButtonGroup.Visible = 0;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            %app.VehicleDropDown.
            % Change active axes to the UIAxes in the current tab
            for k=1:length(selectedTab.Children)
                if strcmp( class(selectedTab.Children(k)), class(app.UIAxes) )
                    % Set active axes
                    app.activeAxes = selectedTab.Children(k);
                    break;
                end
            end
            
            
        end

        % Selection changed function: PlotVehicleDataButtonGroup
        function PlotVehicleDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotVehicleDataButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Maximum Acceleration'
                    data = app.vehicleModel.maxAcceleration.data;
                    xlabel( app.activeAxes, 'velocity [m/s]')
                    ylabel( app.activeAxes, 'acceleration [m/s^2]' )
                case 'Maximum Deceleration'
                    data = app.vehicleModel.maxDeceleration.data;
                    xlabel( app.activeAxes, 'velocity [m/s]')
                    ylabel( app.activeAxes, 'acceleration [m/s^2]' )
                case 'Maximum Cornering Velocity'
                    data = app.vehicleModel.maxCorneringVelocity.data;
                    xlabel( app.activeAxes, 'curvature [1]')
                    ylabel( app.activeAxes, 'velocity [m/s]' )
                otherwise
                    error('Invalid button text, plase update the app')
            end
            
            % Plot data
            app.plot(   data(:,1),       ...
                        data(:,2) );
        end

        % Value changed function: ObjectiveDropDown
        function ObjectiveDropDownValueChanged(app, event)
            value                           = app.ObjectiveDropDown.Value;
            app.objectiveFunction           = eval( ['@', value] );
            % Update evaluator
            app.setCommonEvaluatorProperties('targetFunction',app.objectiveFunction)
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            value                               = app.TrackDropDown.Value;
            app.track = eval( [ value, '()'] );
            app.ParameterMultiplierSlider.Value = 1.0;
            app.ParameterCountLabel.Text        = num2str(length(app.track.bound1));
            app.plotFunction( @(ax)plotTrackOnAxes(ax,app.track), 'axis equal' );
        end

        % Button pushed function: ConfigureOptimizerButton
        function ConfigureOptimizerButtonPushed(app, event)
            app.optimizationOptions = optimizerSetup(app.optimizationOptions);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if strcmp(app.RunButton.Text,'Run')
                app.terminateOptimization   = false;
                app.RunButton.Text          = 'Stop';
                app.setCommonEvaluatorProperties('norm',1.0);
                if ~app.SectionwiseopimizationCheckBox.Value
                    [app.params,app.track] =                                ...
                    optimizePath(           'track',        app.track,      ...
                                            'evaluator',    app.evaluator,  ...
                                            'axesHandle',   app.activeAxes, ...
                                            'optimoptions', parseOptimizationOptions(app.optimizationOptions)   );
                else
                    [app.params,app.track] =                                                        ...
                    optimizePathIterative(  app.evaluator,app.localEvaluator,app.sectionRefinement, ...
                                            'track',        app.track,                              ...
                                            'axesHandle',   app.activeAxes,                         ...
                                            'optimoptions', parseOptimizationOptions(app.optimizationOptions)   );
                end
                app.RunButton.Text          = 'Run';
            else
                app.terminateOptimization   = true;
                app.RunButton.Text          = 'Run';
            end

            app.runPostProcessor();
        end

        % Button pushed function: ConfigureEvaluatorButton
        function ConfigureEvaluatorButtonPushed(app, event)
            app.evaluatorSettings = evaluatorSetup(app.evaluatorSettings);
            for k=1:length(app.evaluatorSettings)
                app.evaluator.(app.evaluatorSettings{k,1}) = str2double(app.evaluatorSettings{k,2});
            end
        end

        % Value changed function: ParameterMultiplierSlider
        function ParameterMultiplierSliderValueChanged(app, event)
            value                           = app.ParameterMultiplierSlider.Value;
            trackName                       = app.TrackDropDown.Value;
            app.track                       = eval([trackName,'(',num2str(value),')']);
            app.ParameterCountLabel.Text    = num2str(length(app.track.bound1));
            app.plotFunction( @(ax)plotTrackOnAxes(ax,app.track), 'axis equal' );
        end

        % Button pushed function: ConfigureLocalEvaluatorButton
        function ConfigureLocalEvaluatorButtonPushed(app, event)
            app.localEvaluatorSettings = evaluatorSetup(app.localEvaluatorSettings);
            for k=1:length(app.evaluatorSettings)
                app.localEvaluator.(app.localEvaluatorSettings{k,1}) = str2double(app.localEvaluatorSettings{k,2});
            end
        end

        % Value changed function: SectionRefinementSlider
        function SectionRefinementSliderValueChanged(app, event)
            value = app.SectionRefinementSlider.Value;
            app.sectionRefinement           = value;
            app.sectionRefinementLabel.Text = num2str(value);
        end

        % Value changed function: SectionwiseopimizationCheckBox
        function SectionwiseopimizationCheckBoxValueChanged(app, event)
            app.toggleSectionwiseSettings();
        end

        % Selection changed function: 
        % DoyouwanttochoosevehicleparametersButtonGroup
        function DoyouwanttochoosevehicleparametersButtonGroupSelectionChanged(app, event)
            on_off(app);
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            set_parm(app)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % function to run the simulation depending on the user choice
            switch app.SimulationOptionsDropDown.Value
                case 'XY Plotter (2D)'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')
                set_param('MPC_Controller/Visualization/UE','commented','on')
                controller_run()
                case 'Unreal Engine (3D)'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')
                set_param('MPC_Controller/Visualization/Visualization','commented','on')
                controller_run()
                case 'Both'
                controller_call(app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
                    app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value)    
                set_param('MPC_Controller/Visualization/Visualization','commented','off')  
                set_param('MPC_Controller/Visualization/UE','commented','off')                
                controller_run()
            end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            % Function to start the optimization process
            set_parm(app)
            [mass_check,wheelbase_check,cog_check,h_check] = parameters(app);
            [p,info]=parameterEstimation_app(mass_check,wheelbase_check,cog_check,h_check,app.MassRangeEditField.Value, ...
            app.MassRangeEditField_max.Value,app.WheelbaseRangeEditField.Value,app.WheelbaseRangeEditField_max.Value,      ...
            app.COGRangeEditField.Value, app.COGRangeEditField_max.Value,app.AxleHeightRangeEditField.Value, ...
            app.AxleHeightRangeEditField_max.Value,app.VehicleMassEditField.Value,app.VehicleWheelbaseEditField.Value, ...
            app.CenterofGravitylocationfromthefrontaxleEditField.Value,app.AxleHeightEditField.Value);
        end

        % Close request function: PathOptimizationAppUIFigure
        function PathOptimizationAppUIFigureCloseRequest(app, event)
            delete(app)
            evalin('base','clear app');
        end

        % Selection changed function: 
        % RoadandTireConditionButtonGroup
        function RoadandTireConditionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.RoadandTireConditionButtonGroup.SelectedObject;
            
            if app.WetButton.Value == 1
                app.mu = 0.7;
            elseif app.ModerateButton_2.Value ==1
                app.mu = 1;
            elseif app.DryButton.Value ==1
                app.mu = 1.5;
            end
            
            if strcmp(app.vehicleName,'SimulinkModel')
                app.vehicleString = 'SimulinkModel';
            elseif strcmp(app.vehicleName,'Simulink3DOF')
                app.vehicleString = 'Simulink3DOF';
            end
            GeneralVelCurv(app.mu,app.vehicleString)
            
            
            if strcmp(app.vehicleName, 'SimulinkModel')
                app_sorting_accelerationTest_14DOF(app.mu);
                app_sorting_decelerationTest_14DOF(app.mu);
            elseif strcmp(app.vehicleModel, 'Simulink3DOF')
                app_sorting_accelerationTest_3DOF(app.mu);
                app_sorting_decelerationTest_3DOF(app.mu);
            end
            
            app.VehicleDropDownValueChanged('NoneEvent')
            %app.PlotVehicleDataButtonGroupSelectionChanged('NoneEvent');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PathOptimizationAppUIFigure = uifigure('Visible', 'off');
            app.PathOptimizationAppUIFigure.Name = 'Path Optimization App';
            app.PathOptimizationAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @PathOptimizationAppUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.PathOptimizationAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ModelSetupTab = uitab(app.TabGroup);
            app.ModelSetupTab.Title = 'Model Setup';
            app.VehicleDropDownLabel = uilabel(app.ModelSetupTab);
            app.VehicleDropDownLabel.Text = 'Vehicle';
            app.VehicleDropDown = uidropdown(app.ModelSetupTab);
            app.VehicleDropDown.Items = {};
            app.VehicleDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleDropDownValueChanged, true);
            app.VehicleDropDown.Value = {};
            app.UIAxes = uiaxes(app.ModelSetupTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PlotVehicleDataButtonGroup = uibuttongroup(app.ModelSetupTab);
            app.PlotVehicleDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotVehicleDataButtonGroupSelectionChanged, true);
            app.PlotVehicleDataButtonGroup.Title = 'Plot Vehicle Data';
            app.MaximumAccelerationButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumAccelerationButton.Text = 'Maximum Acceleration';
            app.MaximumAccelerationButton.Value = true;
            app.MaximumDecelerationButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumDecelerationButton.Text = 'Maximum Deceleration';
            app.MaximumCorneringVelocityButton = uiradiobutton(app.PlotVehicleDataButtonGroup);
            app.MaximumCorneringVelocityButton.Text = 'Maximum Cornering Velocity';
            app.RoadandTireConditionButtonGroup = uibuttongroup(app.ModelSetupTab);
            app.RoadandTireConditionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RoadandTireConditionButtonGroupSelectionChanged, true);
            app.RoadandTireConditionButtonGroup.Title = 'Road and Tire Condition';
            app.ModerateButton_2 = uiradiobutton(app.RoadandTireConditionButtonGroup);
            app.ModerateButton_2.Text = 'Moderate';
            app.ModerateButton_2.Value = true;
            app.WetButton = uiradiobutton(app.RoadandTireConditionButtonGroup);
            app.WetButton.Text = 'Wet';
            app.DryButton = uiradiobutton(app.RoadandTireConditionButtonGroup);
            app.DryButton.Text = 'Dry';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.ObjectiveDropDownLabel = uilabel(app.OptimizationTab);
            app.ObjectiveDropDownLabel.Text = 'Objective';
            app.ObjectiveDropDown = uidropdown(app.OptimizationTab);
            app.ObjectiveDropDown.Items = {};
            app.ObjectiveDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectiveDropDownValueChanged, true);
            app.ObjectiveDropDown.Value = {};
            app.TrackDropDownLabel = uilabel(app.OptimizationTab);
            app.TrackDropDownLabel.Text = 'Track';
            app.TrackDropDown = uidropdown(app.OptimizationTab);
            app.TrackDropDown.Items = {};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Value = {};
            app.UIAxes2 = uiaxes(app.OptimizationTab);
            title(app.UIAxes2, 'Track')
            xlabel(app.UIAxes2, 'x [m]')
            ylabel(app.UIAxes2, 'y [m]')
            app.ConfigureOptimizerButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureOptimizerButtonPushed, true);
            app.ConfigureOptimizerButton.Text = 'Configure Optimizer';
            app.SectionwiseopimizationCheckBox = uicheckbox(app.OptimizationTab);
            app.SectionwiseopimizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SectionwiseopimizationCheckBoxValueChanged, true);
            app.SectionwiseopimizationCheckBox.Text = 'Sectionwise opimization';
            app.RunButton = uibutton(app.OptimizationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConfigureEvaluatorButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureEvaluatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureEvaluatorButtonPushed, true);
            app.ConfigureEvaluatorButton.Text = 'Configure Evaluator';
            app.ParameterMultiplierLabel = uilabel(app.OptimizationTab);
            app.ParameterMultiplierLabel.Text = {'Parameter'; 'Multiplier'};
            app.ParameterMultiplierSlider = uislider(app.OptimizationTab);
            app.ParameterMultiplierSlider.Limits = [0.1 3];
            app.ParameterMultiplierSlider.MajorTicks = [0.1 1 2 3];
            app.ParameterMultiplierSlider.ValueChangedFcn = createCallbackFcn(app, @ParameterMultiplierSliderValueChanged, true);
            app.ParameterMultiplierSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.ParameterMultiplierSlider.Value = 1;
            app.NumberofParametersLabel = uilabel(app.OptimizationTab);
            app.NumberofParametersLabel.Text = 'Number of Parameters: ';
            app.ParameterCountLabel = uilabel(app.OptimizationTab);
            app.ParameterCountLabel.Text = '#';
            app.ConfigureLocalEvaluatorButton = uibutton(app.OptimizationTab, 'push');
            app.ConfigureLocalEvaluatorButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureLocalEvaluatorButtonPushed, true);
            app.ConfigureLocalEvaluatorButton.Text = 'Configure Local Evaluator';
            app.SectionRefinementSliderLabel = uilabel(app.OptimizationTab);
            app.SectionRefinementSliderLabel.Text = {'Section'; 'Refinement'};
            app.SectionRefinementSlider = uislider(app.OptimizationTab);
            app.SectionRefinementSlider.Limits = [1 5];
            app.SectionRefinementSlider.MajorTicks = [1 2 3 4 5];
            app.SectionRefinementSlider.ValueChangedFcn = createCallbackFcn(app, @SectionRefinementSliderValueChanged, true);
            app.SectionRefinementSlider.Value = 2;
            app.sectionRefinementLabel = uilabel(app.OptimizationTab);
            app.sectionRefinementLabel.Text = '#';
            app.OptimizationResultsTab = uitab(app.TabGroup);
            app.OptimizationResultsTab.Title = 'Optimization Results';
            app.VehicleParametersTab = uitab(app.TabGroup);
            app.VehicleParametersTab.Title = 'Vehicle Parameters';
            app.DoyouwanttochoosevehicleparametersButtonGroup = uibuttongroup(app.VehicleParametersTab);
            app.DoyouwanttochoosevehicleparametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DoyouwanttochoosevehicleparametersButtonGroupSelectionChanged, true);
            app.DoyouwanttochoosevehicleparametersButtonGroup.Title = 'Do you want to choose vehicle parameter(s)?';
            app.YesButton = uiradiobutton(app.DoyouwanttochoosevehicleparametersButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoUsethedefaultparametersButton = uiradiobutton(app.DoyouwanttochoosevehicleparametersButtonGroup);
            app.NoUsethedefaultparametersButton.Text = 'No (Use the default parameter(s))';
            app.EntertheParametersLabel = uilabel(app.VehicleParametersTab);
            app.EntertheParametersLabel.Text = 'Enter the Parameter(s):';
            app.EntertheParametersLabel_2 = uilabel(app.VehicleParametersTab);
            app.EntertheParametersLabel_2.Text = 'Note: Choosing unsuitable parameters may lead to undesired simulation';
            app.SubmitButton = uibutton(app.VehicleParametersTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.VehicleMassEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.VehicleMassEditFieldLabel.Text = 'Vehicle Mass';
            app.VehicleMassEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.VehicleWheelbaseEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.VehicleWheelbaseEditFieldLabel.Text = 'Vehicle Wheelbase';
            app.VehicleWheelbaseEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.AxleHeightEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.AxleHeightEditFieldLabel.Text = 'Axle Height';
            app.AxleHeightEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.CenterofGravitylocationfromthefrontaxleEditFieldLabel = uilabel(app.VehicleParametersTab);
            app.CenterofGravitylocationfromthefrontaxleEditFieldLabel.Text = 'Center of Gravity location from the front axle';
            app.CenterofGravitylocationfromthefrontaxleEditField = uieditfield(app.VehicleParametersTab, 'numeric');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.PleasechoosehowdoyouwanttosimulatetheresultsLabel = uilabel(app.SimulationTab);
            app.PleasechoosehowdoyouwanttosimulatetheresultsLabel.Text = 'Please choose how do you want to simulate the results:';
            app.StartButton = uibutton(app.SimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SimulationOptionsDropDownLabel = uilabel(app.SimulationTab);
            app.SimulationOptionsDropDownLabel.Text = 'Simulation Options';
            app.SimulationOptionsDropDown = uidropdown(app.SimulationTab);
            app.SimulationOptionsDropDown.Items = {'XY Plotter (2D)', 'Unreal Engine (3D)', 'Both'};
            app.SimulationOptionsDropDown.Value = 'XY Plotter (2D)';
            app.ParameterEstimationTab = uitab(app.TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.WhichParametersdoyouwanttooptimizeLabel = uilabel(app.ParameterEstimationTab);
            app.WhichParametersdoyouwanttooptimizeLabel.Text = 'Which Parameter(s) do you want to optimize?';
            app.VehicleMassCheckBox = uicheckbox(app.ParameterEstimationTab);
            app.VehicleMassCheckBox.Text = 'Vehicle Mass';
            app.VehicleWheelbaseCheckBox = uicheckbox(app.ParameterEstimationTab);
            app.VehicleWheelbaseCheckBox.Text = 'Vehicle Wheelbase';
            app.LocationofcenterofgravityCheckBox = uicheckbox(app.ParameterEstimationTab);
            app.LocationofcenterofgravityCheckBox.Text = 'Location of center of gravity ';
            app.AxleHeightCheckBox = uicheckbox(app.ParameterEstimationTab);
            app.AxleHeightCheckBox.Text = 'Axle Height';
            app.StartButton_2 = uibutton(app.ParameterEstimationTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.MassRangeEditField_max = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.Label = uilabel(app.ParameterEstimationTab);
            app.Label.Text = '-';
            app.WheelbaseRangeEditField_max = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.Label_2 = uilabel(app.ParameterEstimationTab);
            app.Label_2.Text = '-';
            app.COGRangeEditField_max = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.Label_3 = uilabel(app.ParameterEstimationTab);
            app.Label_3.Text = '-';
            app.AxleHeightRangeEditField_max = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.Label_4 = uilabel(app.ParameterEstimationTab);
            app.Label_4.Text = '-';
            app.MassRangeEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.MassRangeEditFieldLabel.Text = 'Mass Range';
            app.MassRangeEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.WheelbaseRangeEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.WheelbaseRangeEditFieldLabel.Text = 'Wheelbase Range';
            app.WheelbaseRangeEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.COGRangeEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.COGRangeEditFieldLabel.Text = 'COG Range';
            app.COGRangeEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.AxleHeightRangeEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.AxleHeightRangeEditFieldLabel.Text = 'Axle Height Range';
            app.AxleHeightRangeEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.ResultsLabel = uilabel(app.ParameterEstimationTab);
            app.ResultsLabel.Text = 'Results';
            app.MassEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.WheelbaseEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.WheelbaseEditFieldLabel.Text = 'Wheelbase';
            app.WheelbaseEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.COGEditFieldLabel = uilabel(app.ParameterEstimationTab);
            app.COGEditFieldLabel.Text = 'COG';
            app.COGEditField = uieditfield(app.ParameterEstimationTab, 'numeric');
            app.AxleHeightEditField_2Label = uilabel(app.ParameterEstimationTab);
            app.AxleHeightEditField_2Label.Text = 'Axle Height';
            app.AxleHeightEditField_2 = uieditfield(app.ParameterEstimationTab, 'numeric');
            % Show the figure after all components are created
            app.PathOptimizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathOptimizationApp_X

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PathOptimizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PathOptimizationAppUIFigure)
        end
    end
end