classdef trackSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectthetrackDropDownLabel  matlab.ui.control.Label
        SelectthetrackDropDown       matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        PlotthetrackButton           matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectthetrackDropDown
        function SelectthetrackDropDownValueChanged(app, event)
           
             
        end

        % Button pushed function: PlotthetrackButton
        function PlotthetrackButtonPushed(app, event)
            hold(app.UIAxes,"off");
            [x1,y1,x2,y2] = trackForApp(app.SelectthetrackDropDown.Value);
            plot(app.UIAxes,x1,y1); 
            hold (app.UIAxes,'on');
            axis(app.UIAxes, "equal");
            plot(app.UIAxes,x2,y2);
            axis(app.UIAxes,'auto');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectthetrackDropDownLabel = uilabel(app.UIFigure);
            app.SelectthetrackDropDownLabel.Text = 'Select the track';
            app.SelectthetrackDropDown = uidropdown(app.UIFigure);
            app.SelectthetrackDropDown.Items = {'Nurburgring', 'Hockenheimring', 'Circuit_do_Estoril', 'Hangaroring', 'Buddh_International_Circuit', ''};
            app.SelectthetrackDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthetrackDropDownValueChanged, true);
            app.SelectthetrackDropDown.Value = 'Nurburgring';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotthetrackButton = uibutton(app.UIFigure, 'push');
            app.PlotthetrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotthetrackButtonPushed, true);
            app.PlotthetrackButton.Text = 'Plot the track';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trackSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end